classdef UIFeaturesMap < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        EventCheckBox
        EventSelection
    end

    properties (Access = public)      
        CellSelected (1,2) double    
    end

    properties (Access = private)
        GridMain            matlab.ui.container.GridLayout 
        GridTitle           matlab.ui.container.GridLayout   
        UIAxes              matlab.ui.control.UIAxes
        MapHandler          matlab.graphics.primitive.Image
        InnerMapGridHandler matlab.graphics.chart.primitive.Surface
        TitleCustom         matlab.ui.control.CheckBox  
        TitleStylePrv       char
        TitlePrv            char
    end

    properties (Access = public, Dependent)
        Title       char
        XLabel      char 
        XTick       double
        XTickLabel  char
        YLabel      char
        YTick       double
        YTickLabel  char
        Data        double  
        Axes 
        TitleStyle  (1, :) char {mustBeMember(TitleStyle,{'normal','custom'})} = 'normal'
        ColorBar    char = 'off'
    end

    % Set/get methods for properties
    methods 

        function set.Data(comp, value)
            comp.MapHandler               = imagesc(comp.UIAxes, value);
            comp.MapHandler.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @on_mouse_click, true);
            comp.InnerMapGridHandler      = set_map_image_grid(comp);
        end

        function set.Axes(comp, value)
            comp.UIAxes = value;
        end

        function set.Title(comp, value)
            comp.TitlePrv = value;
            comp.set_title_text(value);
        end

        function set.TitleStyle(comp, value)
            comp.TitleStylePrv = value;
            if strcmpi(value, 'custom')
                comp.GridMain.RowHeight = {20, '1x'};               
            else
                comp.GridMain.RowHeight = {0, '1x'};               
            end
            comp.set_title_text(comp.TitlePrv);
        end

        function set.XLabel(comp, value)
            xlabel(comp.UIAxes, value);
        end

        function set.YLabel(comp, value)
            ylabel(comp.UIAxes, value);
        end

        function set.XTick(comp, value)
            xticks(comp.UIAxes, value);
        end

        function set.YTick(comp, value)
            yticks(comp.UIAxes, value);
        end

        function set.XTickLabel(comp, value)
            xticklabels(comp.UIAxes, value);
        end

        function set.YTickLabel(comp, value)
            yticklabels(comp.UIAxes, value);
        end

        function set.ColorBar(comp, value)
            if(strcmpi(value, 'on'))
                colorbar(comp.UIAxes);
            elseif(strcmpi(value, 'off'))
                colorbar(comp.UIAxes, 'off');
            end
        end

        function value = get.Data(comp)
            value = comp.MapHandler.CData;
        end

        function value = get.Title(comp)
            value = comp.TitlePrv;
        end

        function value = get.XLabel(comp)
            value = comp.UIAxes.XLabel;
        end

        function value = get.YLabel(comp)
            value = comp.UIAxes.YLabel;
        end

        function value = get.XTick(comp)
            value = comp.UIAxes.XTick;
        end

        function value = get.YTick(comp)
            value = comp.UIAxes.YTick;
        end

        function value = get.XTickLabel(comp)
            value = comp.UIAxes.XTickLabel;
        end

        function value = get.YTickLabel(comp)
            value = comp.UIAxes.YTickLabel;
        end

        function value = get.Axes(comp)
            value = comp.UIAxes;
        end
   
        function value = get.ColorBar(comp)
            value = colorbar(comp.UIAxes);
        end
    end
    
    methods (Access = public)
        
        function set_cell(comp, value, rowId, colId)
            
            if(rowId > size(comp.Data, 1))
                return
            end

            if(colId > size(comp.Data, 2))
                return
            end

            comp.Data(rowId, colId) = value;
        end

        function reset(comp)
            comp.Data = zeros(size(comp.Data));
        end
    end
    
    methods (Access = private)
        
        function hmesh = set_map_image_grid(comp)            
            s = [size(comp.Data, 1) size(comp.Data, 2)];
            xrange = [1 size(comp.Data, 2)];
            yrange = [1 size(comp.Data, 1)];
            dx = diff(xrange)/(s(2)-1);
            dy = diff(yrange)/(s(1)-1);
            xg = linspace(xrange(1)-dx/2,xrange(2)+dx/2,s(2)+1);
            yg = linspace(yrange(1)-dy/2,yrange(2)+dy/2,s(1)+1);
            hold(comp.UIAxes, 'on');
            
            hmesh = mesh(comp.UIAxes, xg,yg,zeros(s+1));
            hmesh.FaceColor = 'none';
            hmesh.EdgeColor = [.4 .4 .4];
            hold(comp.UIAxes, 'off');
        end
        
        function on_mouse_click(comp, ~)
            cpoint = round(comp.UIAxes.CurrentPoint);
            comp.CellSelected = fliplr(cpoint(1,1:2));
            notify(comp, 'EventSelection');
        end

        function on_checkbox_changed(comp, event)
            notify(comp, 'EventCheckBox', event);
        end

        function set_map_image(comp)
            comp.UIAxes               = uiaxes(comp.GridMain);
            comp.UIAxes.Layout.Column = 1;
            comp.UIAxes.Layout.Row = 2;
                       
            axis(comp.UIAxes, 'image');
            colormap(comp.UIAxes, 'Jet');
            axtoolbar(comp.UIAxes, 'Visible', 'off');
            datacursormode(comp.UIAxes, 'off');
            disableDefaultInteractivity(comp.UIAxes);

            comp.Data = zeros(16, 23);         
        end
        
        function set_grid_main(comp)            
            comp.GridMain               = uigridlayout(comp);
            comp.GridMain.ColumnSpacing = 0;
            comp.GridMain.RowSpacing    = 0;
            comp.GridMain.Padding       = [0 0 0 0];
            comp.GridMain.ColumnWidth   = {'1x'};
            comp.GridMain.RowHeight     = {0, '1x'};

        end

        function set_grid_title(comp)
            comp.GridTitle               = uigridlayout(comp.GridMain);
            comp.GridTitle.ColumnSpacing = 0;
            comp.GridTitle.RowSpacing    = 0;
            comp.GridTitle.Padding       = [0 0 0 0];
            comp.GridTitle.RowHeight     = {'1x'};
            comp.GridTitle.ColumnWidth   = {'1x', 'fit', '1x'};
            comp.GridTitle.Layout.Row    = 1;
            comp.GridTitle.Layout.Column = 1;
        end

        function set_title_custom(comp)            

            comp.TitleCustom               = uicheckbox(comp.GridTitle);
            comp.TitleCustom.Text          = 'Title';
            comp.TitleCustom.FontSize      = 14;
            comp.TitleCustom.FontWeight    = 'bold';
            comp.TitleCustom.Layout.Row    = 1;
            comp.TitleCustom.Layout.Column = 2;
            comp.TitleCustom.Value         = true;
            comp.UIAxes.Title.String       = '';
            comp.UIAxes.Title.FontSize     = 14;
            comp.UIAxes.Title.FontWeight   = 'bold';
        end

        function set_title_text(comp, value)
            
            if strcmpi(comp.TitleStylePrv, 'custom') == true
                comp.UIAxes.Title.String = '';
                comp.TitleCustom.Text = value;
            else
                comp.UIAxes.Title.String = value;
                comp.TitleCustom.Text = '';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
         
            % Create Grid Layout
            comp.set_grid_main();
            comp.set_map_image();
            comp.set_grid_title();
            comp.set_title_custom();

            % Set CheckBox callback
            comp.TitleCustom.ValueChangedFcn =  matlab.apps.createCallbackFcn(comp, @on_checkbox_changed, true);
           
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 450 350];
            comp.BackgroundColor = [0.94 0.94 0.94];
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end