classdef UIFeaturesSelector < matlab.apps.AppBase
    %UIFEATURESSELECTOR User interface for features selection (namespace:
    %neuromat.ui)
    %   app = UIFeaturesSelector(DATA, CLASSES, RUNS) creates an user
    %   interface for features selection. DATA is a 3D array [S x N x M]
    %   where the first dimension S represents the observations, N and M
    %   two features dimensions. CLASSES and RUNS are 1-dimension vector
    %   with same length of S representing the class labels and the run
    %   labels for each observation, respectively.
    %   
    %   The user interface is composed by a top panel with the
    %   discriminancy of the DATA computed with respect the CLASSES for
    %   each RUN separetely. By default, the discriminancy is computed with
    %   CVA algorithm. In the bottom panel, the left map represents
    %   the discriminancy for all DATA. User can select features in this
    %   map and they will appear in the next map. Concurrently, in the last
    %   plot the projection of the selected features in the canonical space
    %   is shown.
    %   
    %   app = UIFeaturesSelector(..., 'PropertyName', PropertyValue) sets
    %   the properties of the user interface. In particular, it is possible
    %   to set the following properties:
    %      'XLabel'           The name for the x-axis of the maps 
    %                         (default: 'Index')
    %      'YLabel'           The name for the y-axis of the maps 
    %                         (default: 'Index')
    %      'XTickLabel'       The x-tick labels of the maps 
    %                         (default: 1:size(DATA, 2))   
    %      'YTickLabel'       The y-tick labels of the maps 
    %                         (default: 1:size(DATA, 3)) 
    %      'Method'           Function handle to compute the 
    %                         discriminancy. The method must have     
    %                         the following signature:               
    %                         [dp, tf] = method(data, labels)     
    %                         where data is [OBSERVATION x 
    %                         FEATURES] and labels is 1-dimension  
    %                         vector with the class for each 
    %                         observation. The method must return 
    %                         the discriminancy dp and the 
    %                         projection tf into the canonical 
    %                         space 
    %      'SelectedFeatures' The feaures currently selected in 
    %                         the interface
    %   
    %   To use the interface within a script, launch it throught the
    %   function FeaturesSelector
    %   
    %   See also FEATURESSELECTOR

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridMain        matlab.ui.container.GridLayout
        GridTitle       matlab.ui.container.GridLayout
        TitleFigure     matlab.ui.control.Label
        GridBottom      matlab.ui.container.GridLayout
        GridButton      matlab.ui.container.GridLayout
        ButtonReset     matlab.ui.control.Button
        ButtonSelect    matlab.ui.control.Button
        PlotProjection  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MapStability      neuromat.ui.UIFeaturesMap
        MapSelection      neuromat.ui.UIFeaturesMap
        MapDiscriminancy
        IsClosed          logical = false
    end

    properties (Access = public, Dependent)
        XLabel     char
        YLabel     char
        XTickLabel char
        YTickLabel char    
        UserData
    end

    properties (Access = private)
        GridTop         matlab.ui.container.GridLayout 
        MinimumMapWidth double
        XLabelPvt       char
        YLabelPvt       char
        XTickLabelPvt   char
        YTickLabelPvt   char
        
        Data              
        ClassLabels       
        RunLabels 
        Runs            double
        NumberOfRuns    double 
        SelectedRuns    logical
        Method          function_handle
    end
    
    % Set and Get methods for dependent properties
    methods
        function set.XLabel(app, value)
            app.XLabelPvt = value;
            for mId = 1:length(app.MapDiscriminancy)
                app.MapDiscriminancy(mId).XLabel = app.XLabelPvt;
            end
            app.MapStability.XLabel = app.XLabelPvt;
            app.MapSelection.XLabel = app.XLabelPvt;
        end
        function set.YLabel(app, value)
            app.YLabelPvt = value;
            for mId = 1:length(app.MapDiscriminancy)
                app.MapDiscriminancy(mId).YLabel = app.YLabelPvt;
            end
            app.MapStability.YLabel = app.YLabelPvt;
            app.MapSelection.YLabel = app.YLabelPvt;
        end
        function set.XTickLabel(app, value)
            app.XTickLabelPvt = value;
            for mId = 1:length(app.MapDiscriminancy)
                app.MapDiscriminancy(mId).XTickLabel = app.XTickLabelPvt;
            end
            app.MapStability.XTickLabel = app.XTickLabelPvt;
            app.MapSelection.XTickLabel = app.XTickLabelPvt;
        end
        function set.YTickLabel(app, value)
            app.YTickLabelPvt = value;
            for mId = 1:length(app.MapDiscriminancy)
                app.MapDiscriminancy(mId).YTickLabel = app.YTickLabelPvt;
            end
            app.MapStability.YTickLabel = app.YTickLabelPvt;
            app.MapSelection.YTickLabel = app.YTickLabelPvt;
        end
        function value = get.XLabel(app)
            value = app.XLabelPvt;
        end
        function value = get.YLabel(app)
            value = app.YLabelPvt;
        end
        function value = get.XTickLabel(app)
            value = app.XTickLabelPvt;
        end
        function value = get.YTickLabel(app)
            value = app.YTickLabelPvt;
        end
        function value = get.UserData(app)
            value.features = find( (app.MapSelection.Data)' == 1);
            value.runs = find(app.SelectedRuns);
        end
    end

    methods (Access = private)
        
        % Grid Layouts
        function app = set_layout_topgrid(app)
            app.GridTop               = uigridlayout(app.GridMain, [1 app.NumberOfRuns]);
            app.GridTop.Scrollable    = 'on';
            app.GridTop.ColumnSpacing = 0;
            app.GridTop.RowSpacing    = 0;
            app.GridTop.Padding       = [0 0 0 0];
            app.GridTop.RowHeight     = {'1x'};
            app.GridTop.ColumnWidth   = repmat({'fit'}, app.NumberOfRuns, 1);
            app.GridTop.Layout.Column = 1;
            app.GridTop.Layout.Row    = 2;
        end

        function app = update_layout_topgrid(app, mincolwidth)
            if( app.UIFigure.Position(3) < app.NumberOfRuns * mincolwidth)               
                app.GridTop.ColumnWidth = repmat(mincolwidth, app.NumberOfRuns, 1);
            end
        end

        % Features map
        function map = set_map_features(~, grid, xlabel, ylabel, xticklabel, yticklabel)
            map                 = neuromat.ui.UIFeaturesMap(grid);         
            map.XLabel          = xlabel;
            map.YLabel          = ylabel;
            map.YTickLabel      = yticklabel;
            map.XTickLabel      = xticklabel;
            map.XTick           = 1:length(xticklabel);
            map.YTick           = 1:length(yticklabel);
        end

        function update_stability_map(app)
            selrunid = find(app.SelectedRuns);
            index = false(length(app.RunLabels), 1);
            for rId = 1:length(selrunid)
                index = index | app.RunLabels == app.Runs(selrunid(rId));
            end 
            
            discriminancy = zeros(size(app.Data, 2), size(app.Data, 3))';
            if sum(index) > 0
                discriminancy = app.compute_discriminancy(app.Data(index, :), app.ClassLabels(index));
            end

            app.MapStability.Data = reshape(discriminancy, size(app.Data, 2), size(app.Data, 3))';
        end

        % Projection plot
        function update_projection(app)

            selfeatures = find( (app.MapSelection.Data)' == 1);

            if (isempty(selfeatures))
                app.reset_projection();
                return;
            end

            runid = find(app.SelectedRuns);
            rindex = false(size(app.Data, 1), 1);
            for rId = 1:length(runid)
                rindex = rindex | app.RunLabels == runid(rId);
            end
            
            [~, tf] = app.compute_discriminancy(app.Data(rindex, selfeatures), app.ClassLabels(rindex));

            classes = unique(app.ClassLabels(rindex));
            d1 = tf(app.ClassLabels(rindex) == classes(1), :);
		    d2 = tf(app.ClassLabels(rindex) == classes(2), :);
            
		    pdf(1) = gkdeb(d1);
		    pdf(2) = gkdeb(d2);
               
            app.reset_projection();
            app.plot_projection(pdf, classes);
        end

        function plot_projection(app, pdf, classes)
            nclasses  = length(classes);
            legendstr = cell(nclasses, 1);
            colors    = colororder;
            hold(app.PlotProjection, 'on');
            for cId = 1:length(classes)
                plot(app.PlotProjection, pdf(cId).x, pdf(cId).f, 'color', colors(cId, :), 'LineWidth', 2);
                legendstr{cId} = ['Class: ' num2str(classes(cId))];
            end
            hold(app.PlotProjection, 'off');
            legend(app.PlotProjection, legendstr);
        end
       
        function reset_projection(app)
            cla(app.PlotProjection, 'reset');
            grid(app.PlotProjection, 'on');
            app.PlotProjection.Title.FontSize = 14;
            app.PlotProjection.Title.String   = 'Projection on canonical space';
        end 

        % Discriminability
        function [dp, tf] = compute_discriminancy(app, data, labels)            
            [dp, tf] = app.Method(data, labels);
        end
        
        % Callbacks 
        function on_select_event(app, event)
            cselection = event.Source.CellSelected;
            app.MapSelection.set_cell(1, cselection(1), cselection(2));
            app.update_projection();
        end

        function on_deselect_event(app, event)
            cselection = event.Source.CellSelected;
            app.MapSelection.set_cell(0, cselection(1), cselection(2));
            app.update_projection();
        end

        function on_select_run(app, event)
            runidx = find(arrayfun(@(x) strcmp(x.Title, event.Source.Title), app.MapDiscriminancy));   
            app.SelectedRuns(runidx) = logical(event.Value);            
            app.update_stability_map();
            app.update_projection();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, F, Fk, Rk, options)
            arguments
                app
                F (:, :, :) double
                Fk (:, 1) double
                Rk (:, 1) double
                options.XLabel char = 'Index'
                options.YLabel char = 'Index'
                options.XTickLabel char = ''
                options.YTickLabel char = ''
                options.Method function_handle = @neuromat_cva
                options.MinimumMapWidth double = 600
            end

            % Import data
            app.Data              = F;
            app.ClassLabels       = Fk;
            app.RunLabels         = Rk;
            
            app.Runs              = unique(Rk);
            app.NumberOfRuns      = length(app.Runs);
            app.SelectedRuns      = true(app.NumberOfRuns, 1);

            app.XLabelPvt       = options.XLabel;
            app.YLabelPvt       = options.YLabel;
            app.XTickLabelPvt   = options.XTickLabel;
            app.YTickLabelPvt   = options.YTickLabel;
            app.Method          = options.Method;
            app.MinimumMapWidth = options.MinimumMapWidth;

            % Default value for x- and y-ticks
            if (isempty(app.XTickLabelPvt))
                app.XTickLabelPvt = compose('%d', 1:size(app.Data, 2));
            end

            if(isempty(app.YTickLabelPvt))
                app.YTickLabelPvt = compose('%d', 1:size(app.Data, 1));
            end

            % TopGrid | Setup
            app.set_layout_topgrid();
            app.update_layout_topgrid(app.MinimumMapWidth);

            % TopGrid | Populate
            app.MapDiscriminancy = neuromat.ui.UIFeaturesMap.empty(app.NumberOfRuns, 0);
            
            for mId = 1:app.NumberOfRuns
                app.MapDiscriminancy(mId)               = app.set_map_features(app.GridTop, app.XLabelPvt, app.YLabelPvt, app.XTickLabelPvt, app.YTickLabelPvt);                
                app.MapDiscriminancy(mId).Title         = ['Run ' num2str(mId)];  
                app.MapDiscriminancy(mId).Layout.Row    = 1;
                app.MapDiscriminancy(mId).Layout.Column = mId;
                app.MapDiscriminancy(mId).TitleStyle    = 'custom';
                app.MapDiscriminancy(mId).EventCheckBoxFcn = matlab.apps.createCallbackFcn(app, @on_select_run, true);

                discriminancy = app.compute_discriminancy(app.Data(app.RunLabels == app.Runs(mId), :), app.ClassLabels(app.RunLabels == app.Runs(mId)));
                app.MapDiscriminancy(mId).Data = reshape(discriminancy, size(app.Data, 2), size(app.Data, 3))';          
            end
                
            % BottomGrid | Populate | Stability Map
            app.MapStability                   = app.set_map_features(app.GridBottom, app.XLabelPvt, app.YLabelPvt, app.XTickLabelPvt, app.YTickLabelPvt);            
            app.MapStability.Title             = 'Stability Map';
            app.MapStability.Layout.Row        = 1;
            app.MapStability.Layout.Column     = 1;
            app.MapStability.EventSelectionFcn = matlab.apps.createCallbackFcn(app, @on_select_event, true);
            discriminancy = app.compute_discriminancy(app.Data(:, :), app.ClassLabels);
            app.MapStability.Data              = reshape(discriminancy, size(app.Data, 2), size(app.Data, 3))';

            % BottomGrid | Populate | Selection Map
            app.MapSelection                   = app.set_map_features(app.GridBottom, app.XLabelPvt, app.YLabelPvt, app.XTickLabelPvt, app.YTickLabelPvt);
            app.MapSelection.Data              = zeros(size(app.Data, 2), size(app.Data, 3))';
            app.MapSelection.Title             = 'Selected Features';
            app.MapSelection.Layout.Row        = 1;
            app.MapSelection.Layout.Column     = 2;
            app.MapSelection.EventSelectionFcn = matlab.apps.createCallbackFcn(app, @on_deselect_event, true);
            app.MapSelection.Axes.CLim         = [0 1];
            
            % BottomGrid | Populate | Projection plot
            app.reset_projection();
            

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            
            if isempty(app.NumberOfRuns) == false
                app.update_layout_topgrid(app.MinimumMapWidth);
            end
           
        end

        % Button pushed function: ButtonReset
        function ButtonResetPushed(app, event)
            app.MapSelection.Data = zeros(size(app.Data, 2), size(app.Data, 3))';
            app.reset_projection();
        end

        % Button pushed function: ButtonSelect
        function ButtonSelectPushed(app, event)
            app.IsClosed = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridMain = uigridlayout(app.UIFigure);
            app.GridBottom = uigridlayout(app.GridMain);
            app.PlotProjection = uiaxes(app.GridBottom);
            title(app.PlotProjection, 'Projection on canonical space')
            app.GridButton = uigridlayout(app.GridBottom);
            app.ButtonSelect = uibutton(app.GridButton, 'push');
            app.ButtonSelect.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectPushed, true);
            app.ButtonSelect.Text = 'Select';
            app.ButtonReset = uibutton(app.GridButton, 'push');
            app.ButtonReset.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetPushed, true);
            app.ButtonReset.Text = 'Reset';
            app.GridTitle = uigridlayout(app.GridMain);
            app.TitleFigure = uilabel(app.GridTitle);
            app.TitleFigure.Text = 'Features Selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIFeaturesSelector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end